classdef imgRegMain < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        Toolbar                   matlab.ui.container.Toolbar
        Start                     matlab.ui.container.toolbar.PushTool
        Load                      matlab.ui.container.toolbar.PushTool
        Save                      matlab.ui.container.toolbar.PushTool
        GridLayout9               matlab.ui.container.GridLayout
        TabGroup                  matlab.ui.container.TabGroup
        DataTab                   matlab.ui.container.Tab
        GridLayout5               matlab.ui.container.GridLayout
        GridLayout10              matlab.ui.container.GridLayout
        RemoveSelectedRowsButton  matlab.ui.control.Button
        RegisterImagesButton      matlab.ui.control.Button
        LoadFileButton            matlab.ui.control.Button
        LoadFolderButton          matlab.ui.control.Button
        imageVersionDropDown      matlab.ui.control.DropDown
        data                      matlab.ui.control.Table
        ConfigTab                 matlab.ui.container.Tab
        GridLayout8               matlab.ui.container.GridLayout
        Tree                      matlab.ui.container.Tree
        OptimiserNode             matlab.ui.container.TreeNode
        Node2                     matlab.ui.container.TreeNode
        Node3                     matlab.ui.container.TreeNode
        Node4                     matlab.ui.container.TreeNode
        MetricNode                matlab.ui.container.TreeNode
        Node                      matlab.ui.container.TreeNode
        DataNode                  matlab.ui.container.TreeNode
        Node_3                    matlab.ui.container.TreeNode
        ImageTab                  matlab.ui.container.Tab
        GridLayout4               matlab.ui.container.GridLayout
        UIAxes                    matlab.ui.control.UIAxes
        ContextMenu               matlab.ui.container.ContextMenu
        RemoveRowsMenu            matlab.ui.container.Menu
    end

    
    properties (Access = private)
        config struct %struct representing the config file
        process_load_data_table table %table needed to process additional functions
        previousPivotIndex int16 = [0, 0] %index for changing pivot
    end
    
    methods (Access = private)
        
        function appendLoadData(app, tempTable)
            tempTable.Select = true(height(tempTable),1);
            tempTable.Pivot = false(height(tempTable),1);
            tempTable.tForm = repmat(affinetform2d(), height(tempTable), 1);

            toInit = setdiff(app.data.ColumnName, tempTable.Properties.VariableNames);
            for i=1:length(toInit)
                tempTable.(toInit{i}) = {};
            end

            reorderedTable = tempTable(:, app.data.ColumnName);
            mergedData = [app.data.Data; reorderedTable];
            [~, uniqueIdx] = unique(mergedData.Name, 'stable');
            app.data.Data = mergedData(uniqueIdx, :);
        end

        function tempTable = processLoadData(app, filenames, filepaths)
            tempTable = table(filenames, filepaths);
            tempTable.Properties.VariableNames = {'Name','Path'};    
            
            for i=1:height(app.process_load_data_table)

                columns = cell(1, length(app.process_load_data_table.input_cols{i}));
                for j=1:length(columns)
                    columns{j} = tempTable.(app.process_load_data_table.input_cols{i}{j});
                end
                handle = app.process_load_data_table.handles{i};
                tempTable.(app.process_load_data_table.output_cols{i}) = handle(columns{:});
            end
        end
        
        function addColumn(app, col_name, editable, col_width)
            arguments (Input)
                app 
                col_name char
                editable logical = false
                col_width char = 'auto'
            end
            app.data.ColumnName = [app.data.ColumnName', {col_name}]';
            app.data.ColumnEditable = [app.data.ColumnEditable, editable];
            app.data.ColumnWidth = [app.data.ColumnWidth, col_width];
        end

        function col_name = addConfigColumn(app, column_string)
            tokens = regexp(column_string, '(\w+)\s*=\s*(\w+)\(([\w, ]*)\)', 'tokens');% Extract components from prompt

            tokens = tokens{1}; % Extract inner cell array
            col_name = tokens{1};      % column label
            func_to_run = tokens{2};    % function to run
            func_inputs = strtrim(tokens{3});  %function inputs
            func_inputs_arr = strtrim(strsplit(func_inputs, ','));
            % Construct the function string for str2func
            func_str = sprintf("@(%s) %s(%s)", func_inputs, func_to_run, func_inputs);
            handle = {str2func(func_str)};
            
            addColumn(app, col_name);
        
            new_row = {col_name, handle, {func_inputs_arr}'};
            app.process_load_data_table = [app.process_load_data_table; new_row];
        end
        
        function updateImageVdropdown(app, name)
            %{
            if strcmp(app.imageVersionDropDown.Items{1}, "no image function given")
                app.imageVersionDropDown.Items = {name};
            else 
            
            end
            %}
            app.imageVersionDropDown.Items = [app.imageVersionDropDown.Items, {name}];
            
        end

        function initDataTable(app)

            colNames = {'output_cols', 'handles', 'input_cols'};
            varTypes = {'cell', 'cell', 'cell'};
            app.process_load_data_table = table('Size',[0,length(colNames)],'VariableTypes',varTypes, 'VariableNames',colNames);
            
            for i=1:length(app.config.data.ExtraColumns)
                addConfigColumn(app, app.config.data.ExtraColumns{i});
            end

            for i=1:length(app.config.data.FetchDataCols)
                col_name = addConfigColumn(app, app.config.data.FetchDataCols{i});
                updateImageVdropdown(app, col_name);
            end
            addColumn(app, 'tForm');
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            defaultConfigStr = "default_config.mat";
            loadedStruct = load(fullfile("config",defaultConfigStr), "config");
            app.config = loadedStruct.config;
            

            if strcmp(app.config.name, defaultConfigStr)
                app.config = load(fullfile("config", app.config.name), "config");
            end
            app.initDataTable();

        end

        % Callback function: Load, LoadFolderButton
        function LoadDataDir(app, event)
            [filenames, filepaths] = fetchDir();
            if ~ isequal(length(filenames), 0)
                pathsTable = processLoadData(app, filenames, filepaths);
                appendLoadData(app, pathsTable);
            end
        end

        % Callback function: RemoveRowsMenu, RemoveSelectedRowsButton
        function removeSelectedRows(app, event)
            if ~ strcmp(app.data.SelectionType, 'row')
                error("Selection type is not row");
            end
            app.data.Data(app.data.Selection, :) = [];
            app.data.Selection = [];
        end

        % Button pushed function: LoadFileButton
        function LoadDataFile(app, event)
            [filenames, filepaths] = fetchFile();
            if ~ isequal(length(filenames), 0)
                pathsTable = processLoadData(app, filenames, filepaths);
                appendLoadData(app, pathsTable);
            end
        end

        % Callback function: Start
        function runRegistration(app, event)
           imgLabel = app.imageVersionDropDown.Value;
           selectedImgs = app.data.Data.(imgLabel)(app.data.Data.Select);
           pivot = app.data.Data.(imgLabel)(app.data.Data.Pivot);
           if isempty(pivot)
               pivot = {0};
           end
           transforms = registerImgs(selectedImgs, pivot);
PAI           disp("");
        end

        % Cell edit callback: data
        function fixPivot(app, event)
            previousRow = app.previousPivotIndex(1);
            previousCol = app.previousPivotIndex(2);
            row = event.Indices(1);
            col = event.Indices(2);
            if app.data.ColumnName(col) == "Pivot"
                app.data.Data{row, col} = event.NewData;
                if event.NewData == 1
                    if ~isequal(app.previousPivotIndex, [0,0])
                        app.data.Data{previousRow, previousCol} = 0;
                    end
                    app.previousPivotIndex = [row, col];
                else
                    app.previousPivotIndex = [0,0];
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Toolbar = uitoolbar(app.UIFigure);
            app.Start = uipushtool(app.Toolbar);
            app.Start.ClickedCallback = createCallbackFcn(app, @runRegistration, true);
            app.Start.Icon = fullfile(pathToMLAPP, 'res', 'next-button.png');
            app.Start.Separator = 'on';
            app.Load = uipushtool(app.Toolbar);
            app.Load.ClickedCallback = createCallbackFcn(app, @LoadDataDir, true);
            app.Load.Icon = fullfile(pathToMLAPP, 'res', 'upload.png');
            app.Load.Separator = 'on';
            app.Save = uipushtool(app.Toolbar);
            app.Save.Icon = fullfile(pathToMLAPP, 'res', 'save.png');
            app.Save.Separator = 'on';
            app.GridLayout9 = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout9);
            app.DataTab = uitab(app.TabGroup);
            app.DataTab.Title = 'Data';
            app.GridLayout5 = uigridlayout(app.DataTab);
            app.data = uitable(app.GridLayout5);
            app.data.ColumnName = {'Pivot'; 'Select'; 'Name'; 'Path'};
            app.data.RowName = {};
            app.data.SelectionType = 'row';
            app.data.ColumnEditable = [true true false false];
            app.data.CellEditCallback = createCallbackFcn(app, @fixPivot, true);
            app.GridLayout10 = uigridlayout(app.GridLayout5);
            app.imageVersionDropDown = uidropdown(app.GridLayout10);
            app.imageVersionDropDown.Items = {};
            app.imageVersionDropDown.Placeholder = 'no image function given';
            app.imageVersionDropDown.Value = {};
            app.LoadFolderButton = uibutton(app.GridLayout10, 'push');
            app.LoadFolderButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataDir, true);
            app.LoadFolderButton.Text = 'Load Folder';
            app.LoadFileButton = uibutton(app.GridLayout10, 'push');
            app.LoadFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataFile, true);
            app.LoadFileButton.Text = 'Load File';
            app.RegisterImagesButton = uibutton(app.GridLayout10, 'push');
            app.RegisterImagesButton.Text = {'Register'; 'Images'};
            app.RemoveSelectedRowsButton = uibutton(app.GridLayout10, 'push');
            app.RemoveSelectedRowsButton.ButtonPushedFcn = createCallbackFcn(app, @removeSelectedRows, true);
            app.RemoveSelectedRowsButton.Text = {'Remove'; 'Selected'; 'Rows'};
            app.ConfigTab = uitab(app.TabGroup);
            app.ConfigTab.Title = 'Config';
            app.GridLayout8 = uigridlayout(app.ConfigTab);
            app.Tree = uitree(app.GridLayout8);
            app.OptimiserNode = uitreenode(app.Tree);
            app.OptimiserNode.Text = 'Optimiser';
            app.Node2 = uitreenode(app.OptimiserNode);
            app.Node2.Text = 'Node2';
            app.Node3 = uitreenode(app.OptimiserNode);
            app.Node3.Text = 'Node3';
            app.Node4 = uitreenode(app.OptimiserNode);
            app.Node4.Text = 'Node4';
            app.MetricNode = uitreenode(app.Tree);
            app.MetricNode.Text = 'Metric';
            app.Node = uitreenode(app.MetricNode);
            app.Node.Text = 'Node';
            app.DataNode = uitreenode(app.Tree);
            app.DataNode.Text = 'Data';
            app.Node_3 = uitreenode(app.DataNode);
            app.Node_3.Text = 'Node';
            app.ImageTab = uitab(app.TabGroup);
            app.ImageTab.Title = 'Image';
            app.GridLayout4 = uigridlayout(app.ImageTab);
            app.UIAxes = uiaxes(app.GridLayout4);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.RemoveRowsMenu = uimenu(app.ContextMenu);
            app.RemoveRowsMenu.MenuSelectedFcn = createCallbackFcn(app, @removeSelectedRows, true);
            app.RemoveRowsMenu.Text = 'Remove Rows';
            % Assign app.ContextMenu
            app.data.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = imgRegMain

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end